classdef brainbowcolorselect_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        RightPanel       matlab.ui.container.Panel
        COLORLabel       matlab.ui.control.Label
        blackCheckBox    matlab.ui.control.CheckBox
        BlueCheckBox     matlab.ui.control.CheckBox
        GreenCheckBox    matlab.ui.control.CheckBox
        RedCheckBox      matlab.ui.control.CheckBox
        ROISpinner       matlab.ui.control.Spinner
        ROISpinnerLabel  matlab.ui.control.Label
        UIAxes6_2        matlab.ui.control.UIAxes
        UIAxes5_2        matlab.ui.control.UIAxes
        UIAxes4_2        matlab.ui.control.UIAxes
        UIAxes3_2        matlab.ui.control.UIAxes
        UIAxes6          matlab.ui.control.UIAxes
        UIAxes5          matlab.ui.control.UIAxes
        UIAxes4          matlab.ui.control.UIAxes
        UIAxes3          matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        LeftPanel        matlab.ui.container.Panel
        LoadButton       matlab.ui.control.Button
        UIAxes1          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global blue red green calcium composite outline_gcampx outline_gcampy colorcell numROI

            path='/Users/platel/Desktop/exp/aurelie/to analyse/444152/clean/';
            %name='P2M_444152_221129_plane1_2023_01_12.10-12-20.nwb';
            %variables=
           % load ('/Users/platel/Desktop/exp/analysis/23_08_28_09_51_41P2M_444175_221125_plane1/brainbow.mat', 'blue' );
           % load ('/Users/platel/Desktop/exp/analysis/23_08_28_09_51_41P2M_444175_221125_plane1/brainbow.mat', 'red' );
           % load ('/Users/platel/Desktop/exp/analysis/23_08_28_09_51_41P2M_444175_221125_plane1/brainbow.mat', 'green' );
           % load ('/Users/platel/Desktop/exp/analysis/23_08_28_09_51_41P2M_444175_221125_plane1/brainbow.mat', 'calcium' );
           % load ('/Users/platel/Desktop/exp/analysis/23_08_28_09_51_41P2M_444175_221125_plane1/brainbow.mat', 'composite' );
           % load ('/Users/platel/Desktop/exp/analysis/23_08_28_09_51_41P2M_444175_221125_plane1/brainbow.mat', 'outline_gcampx' );
           % load ('/Users/platel/Desktop/exp/analysis/23_08_28_09_51_41P2M_444175_221125_plane1/brainbow.mat', 'outline_gcampy' );
           %  load ('/Users/platel/Desktop/exp/analysis/23_08_28_09_51_41P2M_444175_221125_plane1/brainbow.mat', 'colorcell' );
          load ('/Users/platel/Desktop/exp/aurelie/to analyse/444152/clean/brainbow.mat', 'blue' );
           load ('/Users/platel/Desktop/exp/aurelie/to analyse/444152/clean/brainbow.mat', 'red' );
           load ('/Users/platel/Desktop/exp/aurelie/to analyse/444152/clean/brainbow.mat', 'green' );
           load ('/Users/platel/Desktop/exp/aurelie/to analyse/444152/clean/brainbow.mat', 'calcium' );
           load ('/Users/platel/Desktop/exp/aurelie/to analyse/444152/clean/brainbow.mat', 'composite' );
           load ('/Users/platel/Desktop/exp/aurelie/to analyse/444152/clean/brainbow.mat', 'outline_gcampx' );
           load ('/Users/platel/Desktop/exp/aurelie/to analyse/444152/clean/brainbow.mat', 'outline_gcampy' );
            load ('/Users/platel/Desktop/exp/aurelie/to analyse/444152/clean/colorcellnew.mat', 'colorcell' );
    
           %,'red') % ,green, composite ,calcium, outline_gcampx, outline_gcampy )
           % blue=imread([path,'aligned_blue.tif']);

           %  red=imread([path,'aligned_red1040.tif']);
           %  % red890=imread([path,'red890.tif']);
           %  green=imread([path,'aligned_green.tif']);
           %  calcium=imread([path,'calcium.tif']);
           % composite=imread([path,'composite.tif']);
                        
            % app.Image2.ImageSource=[path,'aligned_blue.tif']
           imagesc(composite, 'Parent', app.UIAxes1);
           imagesc(calcium, 'Parent', app.UIAxes2);
           colormap (app.UIAxes2,'gray')
           imagesc(composite, 'Parent', app.UIAxes3);
           imagesc(composite, 'Parent', app.UIAxes3_2);
           % colormap (app.UIAxes3,'gray')
           imagesc(red, 'Parent', app.UIAxes4);
           colormap (app.UIAxes4,'gray')
           imagesc(red, 'Parent', app.UIAxes4_2);
           colormap (app.UIAxes4_2,'gray')
           imagesc(green, 'Parent', app.UIAxes5);
           colormap (app.UIAxes5,'gray')
           imagesc(green, 'Parent', app.UIAxes5_2);
           colormap (app.UIAxes5_2,'gray')

           imagesc(blue, 'Parent', app.UIAxes6);
           colormap (app.UIAxes6,'gray')
           imagesc(blue, 'Parent', app.UIAxes6_2);
           colormap (app.UIAxes6_2,'gray')

           % app.UIAxes1.XAxis.Visible = 'off';
           %  app.UIAxes1.YAxis.Visible = 'off';
           colormap (app.UIAxes1,"gray")
           %ROISpinnerValueChanged(app, event)
           % imagesc(blue, 'Parent', app.UIAxes2);
           numROI=1;
           if colorcell(numROI,1)==1
                app.RedCheckBox.Value=true;
                app.COLORLabel.FontColor='red';
            elseif colorcell(numROI,1)==2
                app.GreenCheckBox.Value=true;
                app.COLORLabel.FontColor='green';
            elseif colorcell(numROI,1)==3
                app.BlueCheckBox.Value=true;
                app.COLORLabel.FontColor='blue';
            elseif colorcell(numROI,1)==4
                app.RedCheckBox.Value=true;
                app.GreenCheckBox.Value=true;
                app.COLORLabel.FontColor='yellow';
            elseif colorcell(numROI,1)==5
                app.RedCheckBox.Value=true;
                app.BlueCheckBox.Value=true;
                app.COLORLabel.FontColor='magenta';
            elseif colorcell(numROI,1)==6
                app.BlueCheckBox.Value=true;
                app.GreenCheckBox.Value=true;
                app.COLORLabel.FontColor='cyan';
            elseif colorcell(numROI,1)==7
                app.RedCheckBox.Value=true;
                app.GreenCheckBox.Value=true;
                app.BlueCheckBox.Value=true;    
                app.COLORLabel.FontColor='white';
                elseif colorcell(numROI,1)==8
                    app.COLORLabel.FontColor='black';
            end

        end

        % Value changed function: ROISpinner
        function ROISpinnerValueChanged(app, event)
            global blue red green calcium composite outline_gcampx outline_gcampy colorcell numROI

            % numROI = app.ROISpinner.Value;
            %save current colorvalue
            if app.RedCheckBox.Value==true && app.GreenCheckBox.Value==false && app.BlueCheckBox.Value==false
                colorcell(numROI,1)=1;
            elseif app.RedCheckBox.Value==false && app.GreenCheckBox.Value==true && app.BlueCheckBox.Value ==false
                colorcell(numROI,1)=2;
            elseif app.RedCheckBox.Value==false && app.GreenCheckBox.Value==false && app.BlueCheckBox.Value ==true
                colorcell(numROI,1)=3;
            elseif app.RedCheckBox.Value==true && app.GreenCheckBox.Value==true && app.BlueCheckBox.Value ==false
                colorcell(numROI,1)=4;
            elseif app.RedCheckBox.Value==true && app.GreenCheckBox.Value==false && app.BlueCheckBox.Value ==true
                colorcell(numROI,1)=5;
            elseif app.RedCheckBox.Value==false && app.GreenCheckBox.Value==true && app.BlueCheckBox.Value ==true
                colorcell(numROI,1)=6;
            elseif app.RedCheckBox.Value==true && app.GreenCheckBox.Value==true && app.BlueCheckBox.Value ==true
                colorcell(numROI,1)=7;
            elseif app.blackCheckBox.Value==true 
                 colorcell(numROI,1)=8;
             elseif app.RedCheckBox.Value==false && app.GreenCheckBox.Value==false && app.BlueCheckBox.Value ==false
                colorcell(numROI,1)=8;
            end
    
            save ('/Users/platel/Desktop/exp/aurelie/to analyse/444152/clean/colorcellnew.mat', 'colorcell');

            numROI = app.ROISpinner.Value;
            
%
            app.RedCheckBox.Value=false;
            app.GreenCheckBox.Value=false;
            app.BlueCheckBox.Value=false;
            app.blackCheckBox.Value=false;
            
            
            if colorcell(numROI,1)==1
                app.RedCheckBox.Value=true;
                app.COLORLabel.FontColor='red';
            elseif colorcell(numROI,1)==2
                app.GreenCheckBox.Value=true;
                app.COLORLabel.FontColor='green';
            elseif colorcell(numROI,1)==3
                app.BlueCheckBox.Value=true;
                app.COLORLabel.FontColor='blue';
            elseif colorcell(numROI,1)==4
                app.RedCheckBox.Value=true;
                app.GreenCheckBox.Value=true;
                app.COLORLabel.FontColor='yellow';
            elseif colorcell(numROI,1)==5
                app.RedCheckBox.Value=true;
                app.BlueCheckBox.Value=true;
                app.COLORLabel.FontColor='magenta';
            elseif colorcell(numROI,1)==6
                app.BlueCheckBox.Value=true;
                app.GreenCheckBox.Value=true;
                app.COLORLabel.FontColor='cyan';
            elseif colorcell(numROI,1)==7
                app.RedCheckBox.Value=true;
                app.GreenCheckBox.Value=true;
                app.BlueCheckBox.Value=true;    
                app.COLORLabel.FontColor='white';
                elseif colorcell(numROI,1)==8
                    app.blackCheckBox.Value=true;   
                    app.COLORLabel.FontColor='black';
            end


        %      imagesc(blue, 'Parent', app.UIAxes2);
        %     hold on 
            imagesc(composite, 'Parent', app.UIAxes1);
            
            hold(app.UIAxes1, 'on');
            x=cell2mat(outline_gcampx(numROI));
            y=cell2mat(outline_gcampy(numROI));
            plot(app.UIAxes1,x, y, '.', 'MarkerSize', 4,"Color",'w');
            %zoom on roi 
            text(app.UIAxes1,max(x),max(y),num2str(numROI),"FontSize",8,"Color",'w')
            
            % axis image
            % axis off
            % hold off
            % smallx=
            % smally
            % imagesc(calcium(min(y)-20:max(y)+20,min(x)-20:max(x)+20) , 'Parent', app.UIAxes2);
            imagesc(calcium, 'Parent', app.UIAxes2);
            imagesc(composite, 'Parent', app.UIAxes3);
            imagesc(red, 'Parent', app.UIAxes4);
            imagesc(green, 'Parent', app.UIAxes5);
            imagesc(blue, 'Parent', app.UIAxes6);
            hold(app.UIAxes2, 'on');
            hold(app.UIAxes3, 'on');
            hold(app.UIAxes4, 'on');
            hold(app.UIAxes5, 'on');
            hold(app.UIAxes6, 'on');

            plot(app.UIAxes2,x, y, '.', 'MarkerSize', 4,"Color",'w');
            plot(app.UIAxes3,x, y, '.', 'MarkerSize', 4,"Color",'w');
            plot(app.UIAxes4,x, y, '.', 'MarkerSize', 4,"Color",'w');
            plot(app.UIAxes5,x, y, '.', 'MarkerSize', 4,"Color",'w');
            plot(app.UIAxes6,x, y, '.', 'MarkerSize', 4,"Color",'w');

            minx=min(x)-20;
            if minx<1 
                minx=1;
                maxx=40;
            end
            maxx=max(x)+20;
            if maxx>512
                minx=512-40;
                maxx=512;
            end
            miny= min(y)-20;
            if miny<1 
                miny=1;
                maxy=40;
            end
            maxy=max(y)+20;
            if maxy>512
                miny=512-40;
                maxy=512;
            end

            newXLimit = [minx, maxx]; % Example x-axis limits
            newYLimit = [miny, maxy]; % Example y-axis limits

            xlim(app.UIAxes2, newXLimit);
            ylim(app.UIAxes2, newYLimit);
            xlim(app.UIAxes3, newXLimit);
            ylim(app.UIAxes3, newYLimit);
            xlim(app.UIAxes4, newXLimit);
            ylim(app.UIAxes4, newYLimit);
            xlim(app.UIAxes5, newXLimit);
            ylim(app.UIAxes5, newYLimit);
            xlim(app.UIAxes6, newXLimit);
            ylim(app.UIAxes6, newYLimit);

            xlim(app.UIAxes3_2, newXLimit);
            ylim(app.UIAxes3_2, newYLimit);
            xlim(app.UIAxes4_2, newXLimit);
            ylim(app.UIAxes4_2, newYLimit);
            xlim(app.UIAxes5_2, newXLimit);
            ylim(app.UIAxes5_2, newYLimit);
            xlim(app.UIAxes6_2, newXLimit);
            ylim(app.UIAxes6_2, newYLimit);


            hold(app.UIAxes1, 'off');
            hold(app.UIAxes2, 'off');
            hold(app.UIAxes3, 'off');
            hold(app.UIAxes4, 'off');
            hold(app.UIAxes5, 'off');
            hold(app.UIAxes6, 'off');
            % colormap (app.UIAxes2,'gray')
            % imagesc(composite(min(y)-20:max(y)+20,min(x)-20:max(x)+20) , 'Parent', app.UIAxes3);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.UIAxes1 = uiaxes(app.LeftPanel);
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Calcium')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = '';
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'composite')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTick = [];
            app.UIAxes3.YTickLabel = '';
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'red')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTick = [];
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTick = [];
            app.UIAxes4.YTickLabel = '';
            app.UIAxes5 = uiaxes(app.RightPanel);
            title(app.UIAxes5, 'green')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.XTick = [];
            app.UIAxes5.XTickLabel = '';
            app.UIAxes5.YTick = [];
            app.UIAxes5.YTickLabel = '';
            app.UIAxes6 = uiaxes(app.RightPanel);
            title(app.UIAxes6, 'blue')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.XTick = [];
            app.UIAxes6.XTickLabel = '';
            app.UIAxes6.YTick = [];
            app.UIAxes6.YTickLabel = '';
            app.UIAxes3_2 = uiaxes(app.RightPanel);
            title(app.UIAxes3_2, 'composite')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.XTickLabel = '';
            app.UIAxes3_2.YTick = [];
            app.UIAxes3_2.YTickLabel = '';
            app.UIAxes4_2 = uiaxes(app.RightPanel);
            title(app.UIAxes4_2, 'red')
            zlabel(app.UIAxes4_2, 'Z')
            app.UIAxes4_2.XTick = [];
            app.UIAxes4_2.XTickLabel = '';
            app.UIAxes4_2.YTick = [];
            app.UIAxes4_2.YTickLabel = '';
            app.UIAxes5_2 = uiaxes(app.RightPanel);
            title(app.UIAxes5_2, 'green')
            zlabel(app.UIAxes5_2, 'Z')
            app.UIAxes5_2.XTick = [];
            app.UIAxes5_2.XTickLabel = '';
            app.UIAxes5_2.YTick = [];
            app.UIAxes5_2.YTickLabel = '';
            app.UIAxes6_2 = uiaxes(app.RightPanel);
            title(app.UIAxes6_2, 'blue')
            zlabel(app.UIAxes6_2, 'Z')
            app.UIAxes6_2.XTick = [];
            app.UIAxes6_2.XTickLabel = '';
            app.UIAxes6_2.YTick = [];
            app.UIAxes6_2.YTickLabel = '';
            app.ROISpinnerLabel = uilabel(app.RightPanel);
            app.ROISpinnerLabel.Text = 'ROI #';
            app.ROISpinner = uispinner(app.RightPanel);
            app.ROISpinner.ValueChangedFcn = createCallbackFcn(app, @ROISpinnerValueChanged, true);
            app.ROISpinner.Value = 1;
            app.RedCheckBox = uicheckbox(app.RightPanel);
            app.RedCheckBox.Text = 'Red';
            app.GreenCheckBox = uicheckbox(app.RightPanel);
            app.GreenCheckBox.Text = 'Green';
            app.BlueCheckBox = uicheckbox(app.RightPanel);
            app.BlueCheckBox.Text = 'Blue';
            app.blackCheckBox = uicheckbox(app.RightPanel);
            app.blackCheckBox.Text = 'black';
            app.COLORLabel = uilabel(app.RightPanel);
            app.COLORLabel.Text = 'COLOR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brainbowcolorselect_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end